classdef uipt < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        GridLayout5                  matlab.ui.container.GridLayout
        TabGroup                     matlab.ui.container.TabGroup
        GeometriaTab                 matlab.ui.container.Tab
        GridLayout3                  matlab.ui.container.GridLayout
        GridLayout10                 matlab.ui.container.GridLayout
        HmaxSpinnerLabel             matlab.ui.control.Label
        HmaxSpinner                  matlab.ui.control.Spinner
        NodesCheckBox                matlab.ui.control.CheckBox
        EdgesCheckBox                matlab.ui.control.CheckBox
        ElementsCheckBox             matlab.ui.control.CheckBox
        UIAxes3                      matlab.ui.control.UIAxes
        MallaTab_2                   matlab.ui.container.Tab
        GridLayout3_2                matlab.ui.container.GridLayout
        ResultadosTab                matlab.ui.container.Tab
        GridLayout4                  matlab.ui.container.GridLayout
        GridLayout6                  matlab.ui.container.GridLayout
        ResolverButton               matlab.ui.control.Button
        WorkspaceButton              matlab.ui.control.Button
        SalirButton                  matlab.ui.control.Button
        TabGroup2                    matlab.ui.container.TabGroup
        EstructuraTab                matlab.ui.container.Tab
        GridLayout7                  matlab.ui.container.GridLayout
        GeometriaPanel               matlab.ui.container.Panel
        GridLayout8                  matlab.ui.container.GridLayout
        FormaDropDownLabel           matlab.ui.control.Label
        FormaDropDown                matlab.ui.control.DropDown
        UITable                      matlab.ui.control.Table
        MaterialPanel                matlab.ui.container.Panel
        GridLayout9                  matlab.ui.container.GridLayout
        ModulodeYoungEditFieldLabel  matlab.ui.control.Label
        ModulodeYoungEditField       matlab.ui.control.EditField
        CoeficientePoissonEditFieldLabel  matlab.ui.control.Label
        CoeficientePoissonEditField  matlab.ui.control.EditField
        CondicionesTab               matlab.ui.container.Tab
        CargasTab                    matlab.ui.container.Tab
    end

    
    properties (Access = private)
        Results;
        geom;
        fgeom;
        mmesh;
    end
    
    methods (Access = private)
        
        function plotNodeCoords(app)
            
            app.UIAxes.cla;
            hold(app.UIAxes,"on");
            
            scalex = app.Lx*0.3;
            scaley = app.Ly*0.3;
         
            x=app.Results.t_coord(2,:);
            y=app.Results.t_coord(3,:);
            
            Coord = [x;y]';
            
            plot(app.UIAxes,x,y,'.k','MarkerSize',6);
            
            sx=x(1,end)+scalex;
            sy=y(1,end)+scaley;
        
            axis (app.UIAxes,[0 sx -scaley sy]);

            title (app.UIAxes,'Mallado');
            
            % Dibujando líneas verticales
            count=0;
            for i=1:app.Nelx+1
                count=count+1;
                for j=1:app.Nely 
                    px1=x(1,count);
                    py1=y(1,count);
                    px2=x(1,count+1);
                    py2=y(1,count+1);
                    plot(app.UIAxes,[px1 px2],[py1 py2],'--k','LineWidth',0.1)
                    count=count+1;
                end
            end
            
            % Dibujar lineas horizontales
            for i=1:app.Nely+1
                count=i;
                for j=1:app.Nelx
                    px1=x(1,count);
                    px2=x(1,count+app.Nely+1);
                    py1=y(1,count);
                    py2=y(1,count+app.Nely+1);
                    plot(app.UIAxes,[px1 px2],[py1 py2],'--k','LineWidth',0.5);
                    count=count+app.Nely+1;
                end
            end
            
            % Numero de nodos.
            for ti=1:app.Results.NNtotal
                text(app.UIAxes,Coord(ti,1)+(app.Lx*0.01),Coord(ti,2)+(app.Ly*0.03),...
                    num2str(ti),'Color','k','FontSize',8)
            end
            
        end
        
        function plotDisplacements(app)
            
            x=app.Results.t_coord(2,:);
            y=app.Results.t_coord(3,:);
            
            pini = [x;y]';
            
        end
        
        function plotTriMesh(app)
            
            app.UIAxes3.cla;
            flag = [app.NodesCheckBox.Value, ...
                    app.EdgesCheckBox.Value, ...
                    app.ElementsCheckBox.Value];

            app.mmesh = gtrimesh(app.geom,app.HmaxSpinner.Value);
            plotrimesh(app.UIAxes3, app.mmesh.Mesh, flag);
            
        end
         
        function updateGeom(app)
            
            data = app.UITable.Data.Valor';
            
            switch app.fgeom
                case 1
                    app.geom = holeplate(data(1),data(2),data(3),data(4),data(5));
                case 2
                    app.geom = dholeplate(data(1),data(2),data(3),data(4),data(5),data(6),data(7));
                case 3
                    app.geom = tplate(data(1),data(2),data(3),data(4),data(5));
                case 4
                    app.geom = rhplate(data(1),data(2),data(3));
            end
            disp(app.geom);
        end
        
        function plotGeom(app)
            
%             Pendiente hasta desarrollar un metodo
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SalirButton
        function SalirButtonPushed(app, event)
            app.delete;
        end

        % Button pushed function: ResolverButton
        function ResolverButtonPushed(app, event)


        end

        % Value changed function: FormaDropDown
        function FormaDropDownValueChanged(app, event)
            value = str2double(app.FormaDropDown.Value);
            app.fgeom = value;
            
            switch value
                case 1
%                     disp('HolePlate');
                    props = {'Ancho (W) [mm]', 'Largo (L) [mm]', ...
                             'PosX circulo (cx) [mm]', ...
                             'PosY circulo (cy) [mm]', ...
                             'Radio (r) [mm]'};
                                      
                    sz = categorical([1; 2; 3; 4; 5],1:5,props);
                    num = [40; 200; 100; 20; 10];
                    tdata = table(sz,num,'VariableNames',{'Dimension','Valor'});
                    app.UITable.Data = tdata;
                                    
                    
                case 2
%                     disp('DHolePlate');
                    props = {'Ancho (W) [mm]', 'Largo (L) [mm]', ...
                             'PosX circulo 1 (cx1) [mm]', ...
                             'Radio circulo 1 (r1) [mm]', ...
                             'PosX circulo 2 (cx2) [mm]', ...
                             'Radio circulo 2 (r2) [mm]', ...
                             'Distancia (d) [mm]'};

                    sz = categorical([1;2;3;4;5;6;7],1:7,props);
                    num = [40; 200;100;20;100;10;20];
                    tdata = table(sz,num,'VariableNames',{'Dimension','Valor'});
                    app.UITable.Data = tdata;
                    
                    
                case 3
%                     disp('TPlate');
                    props = {'Ancho (W) [mm]', 'Largo (L) [mm]', ...
                             'PosX circulos (cx) [mm]', ...
                             'Radio circulos (r) [mm]', ...
                             'Distancia (d) [mm]'};
                    
                    sz = categorical([1;2;3;4;5],1:5,props);
                    num = [40; 200;100;20;20];
                    tdata = table(sz,num,'VariableNames',{'Dimension','Valor'});
                    app.UITable.Data = tdata;
                    
                    
                case 4
%                     disp('RingPlate');
                    props = {'Ancho (W) [mm]', 'Largo (L) [mm]', ...
                             'Radio circulo (r) [mm]'};
                         
                    sz = categorical([1;2;3],1:3,props);
                    num = [40; 200;10];
                    tdata = table(sz,num,'VariableNames',{'Dimension','Valor'});
                    app.UITable.Data = tdata;     
            end
            
            app.updateGeom();
            app.plotTriMesh();
            
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            app.updateGeom();        
            app.plotTriMesh();
        end

        % Value changed function: HmaxSpinner
        function HmaxSpinnerValueChanged(app, event)
            app.updateGeom();        
            app.plotTriMesh();
        end

        % Value changed function: NodesCheckBox
        function NodesCheckBoxValueChanged(app, event)
            app.updateGeom();        
            app.plotTriMesh();
        end

        % Value changed function: EdgesCheckBox
        function EdgesCheckBoxValueChanged(app, event)
            app.updateGeom();        
            app.plotTriMesh();
        end

        % Value changed function: ElementsCheckBox
        function ElementsCheckBoxValueChanged(app, event)
            app.updateGeom();        
            app.plotTriMesh();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.GridLayout5 = uigridlayout(app.GridLayout);
            app.TabGroup = uitabgroup(app.GridLayout5);
            app.GeometriaTab = uitab(app.TabGroup);
            app.GeometriaTab.Title = 'Geometria';
            app.GridLayout3 = uigridlayout(app.GeometriaTab);
            app.GridLayout10 = uigridlayout(app.GridLayout3);
            app.HmaxSpinnerLabel = uilabel(app.GridLayout10);
            app.HmaxSpinnerLabel.Text = 'Hmax';
            app.HmaxSpinner = uispinner(app.GridLayout10);
            app.HmaxSpinner.Limits = [1 10];
            app.HmaxSpinner.ValueChangedFcn = createCallbackFcn(app, @HmaxSpinnerValueChanged, true);
            app.HmaxSpinner.Value = 5;
            app.NodesCheckBox = uicheckbox(app.GridLayout10);
            app.NodesCheckBox.ValueChangedFcn = createCallbackFcn(app, @NodesCheckBoxValueChanged, true);
            app.NodesCheckBox.Text = 'Nodes';
            app.EdgesCheckBox = uicheckbox(app.GridLayout10);
            app.EdgesCheckBox.ValueChangedFcn = createCallbackFcn(app, @EdgesCheckBoxValueChanged, true);
            app.EdgesCheckBox.Text = 'Edges';
            app.EdgesCheckBox.Value = true;
            app.ElementsCheckBox = uicheckbox(app.GridLayout10);
            app.ElementsCheckBox.ValueChangedFcn = createCallbackFcn(app, @ElementsCheckBoxValueChanged, true);
            app.ElementsCheckBox.Text = 'Elements';
            app.ElementsCheckBox.Value = true;
            app.UIAxes3 = uiaxes(app.GridLayout3);
            title(app.UIAxes3, '')
            xlabel(app.UIAxes3, '')
            ylabel(app.UIAxes3, '')
            app.MallaTab_2 = uitab(app.TabGroup);
            app.MallaTab_2.Title = 'Malla';
            app.GridLayout3_2 = uigridlayout(app.MallaTab_2);
            app.ResultadosTab = uitab(app.TabGroup);
            app.ResultadosTab.Title = 'Resultados';
            app.GridLayout4 = uigridlayout(app.ResultadosTab);
            app.GridLayout6 = uigridlayout(app.GridLayout5);
            app.ResolverButton = uibutton(app.GridLayout6, 'push');
            app.ResolverButton.ButtonPushedFcn = createCallbackFcn(app, @ResolverButtonPushed, true);
            app.ResolverButton.Text = 'Resolver';
            app.WorkspaceButton = uibutton(app.GridLayout6, 'push');
            app.WorkspaceButton.Text = 'Workspace';
            app.SalirButton = uibutton(app.GridLayout6, 'push');
            app.SalirButton.ButtonPushedFcn = createCallbackFcn(app, @SalirButtonPushed, true);
            app.SalirButton.Text = 'Salir';
            app.TabGroup2 = uitabgroup(app.GridLayout);
            app.EstructuraTab = uitab(app.TabGroup2);
            app.EstructuraTab.Title = 'Estructura';
            app.GridLayout7 = uigridlayout(app.EstructuraTab);
            app.GeometriaPanel = uipanel(app.GridLayout7);
            app.GeometriaPanel.Title = 'Geometria';
            app.GridLayout8 = uigridlayout(app.GeometriaPanel);
            app.FormaDropDownLabel = uilabel(app.GridLayout8);
            app.FormaDropDownLabel.Text = 'Forma';
            app.FormaDropDown = uidropdown(app.GridLayout8);
            app.FormaDropDown.Items = {'Hole Plate', 'Double Hole Plate', 'T Plate', 'Ring Plate'};
            app.FormaDropDown.ItemsData = {'1', '2', '3', '4'};
            app.FormaDropDown.ValueChangedFcn = createCallbackFcn(app, @FormaDropDownValueChanged, true);
            app.FormaDropDown.Tag = 'formSelection';
            app.FormaDropDown.Value = '1';
            app.UITable = uitable(app.GridLayout8);
            app.UITable.ColumnName = {'Dimension'; 'Valor'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = [false true];
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.MaterialPanel = uipanel(app.GridLayout7);
            app.MaterialPanel.Title = 'Material';
            app.GridLayout9 = uigridlayout(app.MaterialPanel);
            app.ModulodeYoungEditFieldLabel = uilabel(app.GridLayout9);
            app.ModulodeYoungEditFieldLabel.Text = 'Modulo de Young';
            app.ModulodeYoungEditField = uieditfield(app.GridLayout9, 'text');
            app.ModulodeYoungEditField.Value = '210000';
            app.CoeficientePoissonEditFieldLabel = uilabel(app.GridLayout9);
            app.CoeficientePoissonEditFieldLabel.Text = 'Coeficiente Poisson';
            app.CoeficientePoissonEditField = uieditfield(app.GridLayout9, 'text');
            app.CoeficientePoissonEditField.Value = '0.3';
            app.CondicionesTab = uitab(app.TabGroup2);
            app.CondicionesTab.Title = 'Condiciones';
            app.CargasTab = uitab(app.TabGroup2);
            app.CargasTab.Title = 'Cargas';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = uipt

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end